classdef Master_Pref_v1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        QbPASSPref                     matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeneralTab                     matlab.ui.container.Tab
        General_SaveButton             matlab.ui.control.StateButton
        General_DefaultButton          matlab.ui.control.StateButton
        CloseButton                    matlab.ui.control.StateButton
        DirectoryLocationLabel         matlab.ui.control.Label
        DirectoryLocation              matlab.ui.control.EditField
        SoftwareVersionEditFieldLabel  matlab.ui.control.Label
        SoftwareVersionEditField       matlab.ui.control.NumericEditField
        UpdateButton                   matlab.ui.control.Button
        GradientThresholdEditFieldLabel  matlab.ui.control.Label
        GradientThresholdEditField     matlab.ui.control.NumericEditField
        MaxChannelThresholdEditFieldLabel  matlab.ui.control.Label
        MaxChannelThresholdEditField   matlab.ui.control.NumericEditField
        DataProcessingLabel            matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        ResetallpreferencevaluesLabel  matlab.ui.control.Label
        DeveloperaccessDropDownLabel   matlab.ui.control.Label
        DeveloperaccessDropDown        matlab.ui.control.DropDown
        FiguresTab                     matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        GlobalPlotSettingsTab          matlab.ui.container.Tab
        LabelFontSizeEditFieldLabel    matlab.ui.control.Label
        LabelFontSizeEditField         matlab.ui.control.NumericEditField
        AxesFontSizeEditFieldLabel     matlab.ui.control.Label
        AxesFontSizeEditField          matlab.ui.control.NumericEditField
        TitleFontSizeEditFieldLabel    matlab.ui.control.Label
        TitleFontSizeEditField         matlab.ui.control.NumericEditField
        NoFigureBackgroundCheckBox     matlab.ui.control.CheckBox
        ColorSelection                 matlab.ui.control.StateButton
        LabelFontWeightDropDown        matlab.ui.control.DropDown
        AxesFontWeightDropDown         matlab.ui.control.DropDown
        TitleFontWeightDropDown        matlab.ui.control.DropDown
        OutputFormatDropDownLabel      matlab.ui.control.Label
        OutputFormatDropDown           matlab.ui.control.DropDown
        Figures_CancelButton           matlab.ui.control.StateButton
        Figures_DefaultButton          matlab.ui.control.StateButton
        Figures_SaveButton             matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        version % Description
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, version)
            app.version = version;
            % General
            app.SoftwareVersionEditField.Value = getpref('QbPASS','version');
            app.DirectoryLocation.Value = getpref('QbPASS','FileDirectory');
            app.GradientThresholdEditField.Value = getpref('QbPASS','Threshold_Grad');
            app.MaxChannelThresholdEditField.Value = getpref('QbPASS','Threshold_Channel');
            
            if strcmp('default',getpref('QbPASS', 'FigLabel_FontSize')) == 0
                app.LabelFontSizeEditField.Value = getpref('QbPASS', 'FigLabel_FontSize');
            end
            app.LabelFontWeightDropDown.Value = getpref('QbPASS', 'FigLabel_FontWeight');
            
            if strcmp('default',getpref('QbPASS', 'FigAxes_FontSize')) == 0
                app.AxesFontSizeEditField.Value = getpref('QbPASS', 'FigAxes_FontSize');
            end
            app.AxesFontWeightDropDown.Value = getpref('QbPASS', 'FigAxes_FontWeight');
            
            if strcmp('default',getpref('QbPASS', 'FigTitle_FontSize')) == 0
                app.TitleFontSizeEditField.Value = getpref('QbPASS', 'FigTitle_FontSize');
            end
            app.TitleFontWeightDropDown.Value = getpref('QbPASS', 'FigTitle_FontWeight');
            
            getpref('QbPASS', 'Fig_Tick_FontSize');
            getpref('QbPASS', 'Fig_Tick_FontWeight');
            
            app.DeveloperaccessDropDown.Value = getpref('QbPASS','DeveloperAccess');


        end

        % Value changed function: General_SaveButton
        function General_SaveButtonValueChanged(app, event)
            % General
            setpref('QbPASS','FileDirectory', app.DirectoryLocation.Value);
            setpref('QbPASS','Threshold_Grad', app.GradientThresholdEditField.Value);
            setpref('QbPASS','Threshold_Channel', app.MaxChannelThresholdEditField.Value);
            setpref('QbPASS','Threshold_Channel', app.MaxChannelThresholdEditField.Value);
            app.General_SaveButton.Value = 0;
            
        end

        % Value changed function: CloseButton, Figures_CancelButton
        function CloseButtonValueChanged(app, event)
            close(app.QbPASSPref)
        end

        % Value changed function: General_DefaultButton
        function General_DefaultButtonValueChanged(app, event)
            app.GradientThresholdEditField.Value = 50;
            app.MaxChannelThresholdEditField.Value = 50;
        end

        % Value changed function: Figures_DefaultButton
        function Figures_DefaultButtonValueChanged(app, event)
            
            % General
            app.LabelFontSizeEditField.Value = 14;
            app.AxesFontSizeEditField.Value = 12;
            app.TitleFontSizeEditField.Value = 14;
            
            app.LabelFontWeightDropDown.Value = 'bold';
            app.AxesFontWeightDropDown.Value = 'normal';
            app.TitleFontWeightDropDown.Value = 'bold';
            
            app.NoFigureBackgroundCheckBox.Value = 1;
            
            app.Figures_DefaultButton.Value = 0;
            
        end

        % Value changed function: Figures_SaveButton
        function Figures_SaveButtonValueChanged(app, event)
            setpref('QbPASS','FigLabel_FontSize', app.LabelFontSizeEditField.Value)
            setpref('QbPASS','FigLabel_FontWeight', app.LabelFontWeightDropDown.Value)
            setpref('QbPASS','FigTitle_FontSize', app.TitleFontSizeEditField.Value)
            setpref('QbPASS','FigTitle_FontWeight', app.TitleFontWeightDropDown.Value)
            setpref('QbPASS','FigAxes_FontSize', app.AxesFontSizeEditField.Value)
            setpref('QbPASS','FigAxes_FontWeight', app.AxesFontWeightDropDown.Value)
            
            setpref('QbPASS', 'outputFormat', app.OutputFormatDropDown.Value)
            
            app.Figures_SaveButton.Value = 0;
            
        end

        % Value changed function: ColorSelection
        function ColorSelectionValueChanged(app, event)
            c = uisetcolor([1 1 0],'Select a color');
            app.ColorSelection.BackgroundColor = c;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            selpath = uigetdir;
            if selpath == 0
            else
                setpref('QbPASS','FileDirectory',selpath)
            end
            FileDirectoryCheck()
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            selection = uiconfirm(app.QbPASSPref,'Are you sure you wish to reset all QbPASS preferences? This operation will remove all presaved preferencess including the directory root which will need to be specified again. This operation cannot be undone.','Confirm Reset',...
                'Icon','warning');
            switch selection
                case 'OK'
                    rmpref('QbPASS');
                    QbPASS_Preference_Check_v2(app.version, app.QbPASSPref);
            end
            
        end

        % Value changed function: DeveloperaccessDropDown
        function DeveloperaccessDropDownValueChanged(app, event)
            setpref('QbPASS','DeveloperAccess',app.DeveloperaccessDropDown.Value)
        end

        % Value changed function: OutputFormatDropDown
        function OutputFormatDropDownValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.QbPASSPref = uifigure('Visible', 'off');
            app.QbPASSPref.Name = 'Preferences';
            app.TabGroup = uitabgroup(app.QbPASSPref);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.General_SaveButton = uibutton(app.GeneralTab, 'state');
            app.General_SaveButton.ValueChangedFcn = createCallbackFcn(app, @General_SaveButtonValueChanged, true);
            app.General_SaveButton.Text = 'Save';
            app.General_DefaultButton = uibutton(app.GeneralTab, 'state');
            app.General_DefaultButton.ValueChangedFcn = createCallbackFcn(app, @General_DefaultButtonValueChanged, true);
            app.General_DefaultButton.Text = 'Default';
            app.CloseButton = uibutton(app.GeneralTab, 'state');
            app.CloseButton.ValueChangedFcn = createCallbackFcn(app, @CloseButtonValueChanged, true);
            app.CloseButton.Text = 'Close';
            app.DirectoryLocationLabel = uilabel(app.GeneralTab);
            app.DirectoryLocationLabel.Text = 'Directory Location';
            app.DirectoryLocation = uieditfield(app.GeneralTab, 'text');
            app.DirectoryLocation.Editable = 'off';
            app.SoftwareVersionEditFieldLabel = uilabel(app.GeneralTab);
            app.SoftwareVersionEditFieldLabel.Text = 'Software Version';
            app.SoftwareVersionEditField = uieditfield(app.GeneralTab, 'numeric');
            app.SoftwareVersionEditField.Editable = 'off';
            app.UpdateButton = uibutton(app.GeneralTab, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.GradientThresholdEditFieldLabel = uilabel(app.GeneralTab);
            app.GradientThresholdEditFieldLabel.Text = 'Gradient Threshold (%)';
            app.GradientThresholdEditField = uieditfield(app.GeneralTab, 'numeric');
            app.MaxChannelThresholdEditFieldLabel = uilabel(app.GeneralTab);
            app.MaxChannelThresholdEditFieldLabel.Text = 'Max Channel Threshold (%)';
            app.MaxChannelThresholdEditField = uieditfield(app.GeneralTab, 'numeric');
            app.MaxChannelThresholdEditField.Limits = [0.001 100];
            app.MaxChannelThresholdEditField.Value = 0.001;
            app.DataProcessingLabel = uilabel(app.GeneralTab);
            app.DataProcessingLabel.Text = 'Data Processing';
            app.ResetButton = uibutton(app.GeneralTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ResetallpreferencevaluesLabel = uilabel(app.GeneralTab);
            app.ResetallpreferencevaluesLabel.Text = 'Reset all preference values';
            app.DeveloperaccessDropDownLabel = uilabel(app.GeneralTab);
            app.DeveloperaccessDropDownLabel.Text = 'Developer access';
            app.DeveloperaccessDropDown = uidropdown(app.GeneralTab);
            app.DeveloperaccessDropDown.Items = {'off', 'on'};
            app.DeveloperaccessDropDown.ValueChangedFcn = createCallbackFcn(app, @DeveloperaccessDropDownValueChanged, true);
            app.DeveloperaccessDropDown.Value = 'off';
            app.FiguresTab = uitab(app.TabGroup);
            app.FiguresTab.Title = 'Figures';
            app.TabGroup2 = uitabgroup(app.FiguresTab);
            app.GlobalPlotSettingsTab = uitab(app.TabGroup2);
            app.GlobalPlotSettingsTab.Title = 'Global Plot Settings';
            app.LabelFontSizeEditFieldLabel = uilabel(app.GlobalPlotSettingsTab);
            app.LabelFontSizeEditFieldLabel.Text = 'Label Font Size';
            app.LabelFontSizeEditField = uieditfield(app.GlobalPlotSettingsTab, 'numeric');
            app.LabelFontSizeEditField.Limits = [1 20];
            app.LabelFontSizeEditField.Value = 14;
            app.AxesFontSizeEditFieldLabel = uilabel(app.GlobalPlotSettingsTab);
            app.AxesFontSizeEditFieldLabel.Text = 'Axes Font Size';
            app.AxesFontSizeEditField = uieditfield(app.GlobalPlotSettingsTab, 'numeric');
            app.AxesFontSizeEditField.Limits = [1 20];
            app.AxesFontSizeEditField.Value = 12;
            app.TitleFontSizeEditFieldLabel = uilabel(app.GlobalPlotSettingsTab);
            app.TitleFontSizeEditFieldLabel.Text = 'Title Font Size';
            app.TitleFontSizeEditField = uieditfield(app.GlobalPlotSettingsTab, 'numeric');
            app.TitleFontSizeEditField.Limits = [1 20];
            app.TitleFontSizeEditField.Value = 14;
            app.NoFigureBackgroundCheckBox = uicheckbox(app.GlobalPlotSettingsTab);
            app.NoFigureBackgroundCheckBox.Text = 'No Figure Background';
            app.NoFigureBackgroundCheckBox.Value = true;
            app.ColorSelection = uibutton(app.GlobalPlotSettingsTab, 'state');
            app.ColorSelection.ValueChangedFcn = createCallbackFcn(app, @ColorSelectionValueChanged, true);
            app.ColorSelection.Text = '';
            app.LabelFontWeightDropDown = uidropdown(app.GlobalPlotSettingsTab);
            app.LabelFontWeightDropDown.Items = {'normal', 'bold'};
            app.LabelFontWeightDropDown.Value = 'bold';
            app.AxesFontWeightDropDown = uidropdown(app.GlobalPlotSettingsTab);
            app.AxesFontWeightDropDown.Items = {'normal', 'bold'};
            app.AxesFontWeightDropDown.Value = 'normal';
            app.TitleFontWeightDropDown = uidropdown(app.GlobalPlotSettingsTab);
            app.TitleFontWeightDropDown.Items = {'normal', 'bold'};
            app.TitleFontWeightDropDown.Value = 'bold';
            app.OutputFormatDropDownLabel = uilabel(app.GlobalPlotSettingsTab);
            app.OutputFormatDropDownLabel.Text = 'Output Format';
            app.OutputFormatDropDown = uidropdown(app.GlobalPlotSettingsTab);
            app.OutputFormatDropDown.Items = {'ps', 'jpeg'};
            app.OutputFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputFormatDropDownValueChanged, true);
            app.OutputFormatDropDown.Value = 'ps';
            app.Figures_CancelButton = uibutton(app.FiguresTab, 'state');
            app.Figures_CancelButton.ValueChangedFcn = createCallbackFcn(app, @CloseButtonValueChanged, true);
            app.Figures_CancelButton.Text = 'Close';
            app.Figures_DefaultButton = uibutton(app.FiguresTab, 'state');
            app.Figures_DefaultButton.ValueChangedFcn = createCallbackFcn(app, @Figures_DefaultButtonValueChanged, true);
            app.Figures_DefaultButton.Text = 'Default';
            app.Figures_SaveButton = uibutton(app.FiguresTab, 'state');
            app.Figures_SaveButton.ValueChangedFcn = createCallbackFcn(app, @Figures_SaveButtonValueChanged, true);
            app.Figures_SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.QbPASSPref.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Master_Pref_v1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.QbPASSPref)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.QbPASSPref)
        end
    end
end