classdef QbPASS_Master < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Qb                            matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        About                         matlab.ui.container.Menu
        PreferencesMenu               matlab.ui.container.Menu
        ImportfilesMenu               matlab.ui.container.Menu
        ERFtemplateMenuImport         matlab.ui.container.Menu
        NewDirectoryMenu              matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        ERFtemplateMenuExport         matlab.ui.container.Menu
        DatasettoxlsMenu              matlab.ui.container.Menu
        OnlineResourcesMenu           matlab.ui.container.Menu
        testMenu                      matlab.ui.container.Menu
        TabGroup                      matlab.ui.container.TabGroup
        InstrumentsTab                matlab.ui.container.Tab
        CytometersListBoxLabel        matlab.ui.control.Label
        CytometersListBox             matlab.ui.control.ListBox
        AddCytometer                  matlab.ui.control.Button
        DatasetsListBoxLabel          matlab.ui.control.Label
        DatasetsListBox               matlab.ui.control.ListBox
        AddDataset                    matlab.ui.control.Button
        CytometerEditFieldLabel       matlab.ui.control.Label
        CytometerEditField            matlab.ui.control.EditField
        DateAcquiredDatePickerLabel   matlab.ui.control.Label
        DateAcquiredDatePicker        matlab.ui.control.DatePicker
        RemoveCytometer               matlab.ui.control.Button
        RemoveDataset                 matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        QCPlotButton                  matlab.ui.control.Button
        AutoPlotDropDownLabel         matlab.ui.control.Label
        AutoPlotDropDown              matlab.ui.control.DropDown
        VoltrationPlotButton          matlab.ui.control.Button
        TrackingPlotButton            matlab.ui.control.Button
        GenerateReportButton          matlab.ui.control.Button
        QCPlotDropDown                matlab.ui.control.DropDown
        VoltrationPlotDropDown        matlab.ui.control.DropDown
        PhiThresholdEditFieldLabel    matlab.ui.control.Label
        PhiThresholdEditField         matlab.ui.control.NumericEditField
        VoltrationTab                 matlab.ui.container.Tab
        UITable2                      matlab.ui.control.Table
        UpdateStatisticsButton        matlab.ui.control.Button
        ParameterDropDown_2Label      matlab.ui.control.Label
        ParameterDropDown_2           matlab.ui.control.DropDown
        MaxVtSliderLabel              matlab.ui.control.Label
        MaxVtSlider                   matlab.ui.control.Slider
        UIAxes2                       matlab.ui.control.UIAxes
        SummaryTab                    matlab.ui.container.Tab
        UITable3                      matlab.ui.control.Table
        InspectTab                    matlab.ui.container.Tab
        LaserStatusDropDownLabel      matlab.ui.control.Label
        LaserStatusDropDown           matlab.ui.control.DropDown
        TestConditionDropDownLabel    matlab.ui.control.Label
        TestConditionDropDown         matlab.ui.control.DropDown
        PulserIntensityDropDownLabel  matlab.ui.control.Label
        PulserIntensityDropDown       matlab.ui.control.DropDown
        ParameterDropDownLabel        matlab.ui.control.Label
        ParameterDropDown             matlab.ui.control.DropDown
        YAxisDropDownLabel            matlab.ui.control.Label
        YAxisDropDown                 matlab.ui.control.DropDown
        XAxisDropDownLabel            matlab.ui.control.Label
        XAxisDropDown                 matlab.ui.control.DropDown
        LogYAxisCheckBox              matlab.ui.control.CheckBox
        LogXAxisCheckBox              matlab.ui.control.CheckBox
        UIAxes                        matlab.ui.control.UIAxes
        ErrorsTab                     matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        MinVoltageSliderLabel         matlab.ui.control.Label
        MinVoltageSlider              matlab.ui.control.Slider
        LogTab                        matlab.ui.container.Tab
        FilenamesListBox              matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        
        
        % Description
    end
    
    properties (Access = public)
        fcshdr
        Stat_Median
        Stat_CV
        Stat_SD
        fcsdata
        ParNames
        ParVt
        ParInd
        Voltage
        Laser_State
        Test_Condition
        Pulser_Intensity
        ERFTemplate
        Range % Description
        Summary % Description
        OptiStats
        Version % Description
        UserInput % Description
        LoadedDataset
        ImportSelpath
        TData % Description
        VtTNames
        VtTData
    end
    
    methods (Access = private)
        
        function PlotGraph(app)
            try
                indA = categorical(app.Laser_State) == app.LaserStatusDropDown.Value;
                indB = categorical(app.Test_Condition) == app.TestConditionDropDown.Value;
                indC = categorical(app.Pulser_Intensity) == app.PulserIntensityDropDown.Value;
                ind = sum([indA(:), indB(:), indC(:)],2);
                ind = ind == 3;
                ind2 = find(contains(unique(app.ParNames,'stable'), app.ParameterDropDown.Value));
                
                [xdata, xstr] = GetStatistic(app, app.XAxisDropDown.Value, ind, ind2);
                [ydata, ystr] = GetStatistic(app, app.YAxisDropDown.Value, ind, ind2);
                
                cla(app.UIAxes,'reset')
                app.UIAxes.BackgroundColor = [1 1 1];
                app.UIAxes.LineWidth = 1;
                scatter(app.UIAxes, xdata, ydata, 50, 'filled')
                xlabel(app.UIAxes, xstr,'FontSize',14,'FontWeight',"bold")
                ylabel(app.UIAxes, ystr,'FontSize',14,'FontWeight',"bold")
                title(app.UIAxes,'')
                box(app.UIAxes,'on')
                grid(app.UIAxes,'minor')
                app.UIAxes.FontSize = 14;
                UIAxisDataScaling(app)
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
        end
        
        function DropDownSelection(app)
            indB = contains(app.Test_Condition, app.TestConditionDropDown.Value);
            
            app.LaserStatusDropDown.Items = unique(app.Laser_State(indB));
            ind = sort(str2double(unique(app.Pulser_Intensity(indB))));
            
            app.PulserIntensityDropDown.Items = strsplit(num2str(ind));
        end
        
        function [data, str] = GetStatistic(app, input, ind, ind2)
            switch input
                
                case 'Intensity'
                    data = app.Stat_Median(ind, ind2);
                    str = 'Intensity (au)';
                case 'SD^2'
                    data = (app.Stat_SD(ind, ind2)).^2;
                    str = 'SD^2';
                    
                case 'CV'
                    data = app.Stat_CV(ind, ind2);
                    str = 'CV';
                    
                case 'Voltage'
                    data = app.ParVt(ind,ind2);
                    str = 'Voltage';
                    
            end
        end
        
        function UIAxisDataScaling(app)
            
            if app.LogYAxisCheckBox.Value
                app.UIAxes.YScale = 'log';
            else
                app.UIAxes.YScale = 'linear';
            end
            
            if app.LogXAxisCheckBox.Value
                app.UIAxes.XScale = 'log';
            else
                app.UIAxes.XScale = 'linear';
            end
            
        end
        
        function ClearAppVars(app)
            app.fcshdr = [];
            app.Stat_Median = [];
            app.Stat_CV = [];
            app.Stat_SD = [];
            app.fcsdata = [];
            app.ParNames= [];
            app.ParVt = [];
            app.ParInd = [];
            app.Voltage = [];
            app.Laser_State= [];
            app.Test_Condition= [];
            app.Pulser_Intensity= [];
            cla(app.UIAxes,'reset')
            app.UITable.Data = [];
            app.UITable2.Data = [];
            app.LoadedDataset = [];
            app.Range = [];
            app.ParameterDropDown.Items = {''};
            app.ParameterDropDown_2.Items = {''};
            app.TestConditionDropDown.Items = {''};
            app.PulserIntensityDropDown.Items = {''};
            app.LaserStatusDropDown.Items = {''};
            
            app.LoadedDataset = [];
            app.Summary = [];
            app.OptiStats = [];
        end


        function PlotExtractedData(app)
            
            ind = app.ParameterDropDown_2.Value;
            xData1 = app.Summary.VtInt;
            xData2 = app.Summary.VtRaw;
            
            OptVolt = app.Summary.VtOpt(ind);
            ParName = app.Summary.ParName{ind};
            
            cla(app.UIAxes2,'reset')
            app.UIAxes2.BackgroundColor = [1 1 1];
            app.UIAxes2.LineWidth = 1;
            if isnan(app.Summary.VtOpt(ind))
                text(app.UIAxes2,0.5,0.5,[ParName,' Indeterminable'],'HorizontalAlignment','center','FontWeight','bold')
                axis(app.UIAxes2,'off')
            else
                yData1 = app.Summary.SP2Int(:,ind);
                yData2 = app.Summary.SP2Raw(:,ind);
                %                 yData3 = app.Summary.SpeInt(:,ind);
                %                 yData4 = app.Summary.SpeRaw(:,ind);
                
                yData3 = app.Summary.F0SDInt(:,ind).^2;
                yData4 = app.Summary.F0SD(:,ind).^2;
                
                % SP2 Data plotting
                yyaxis(app.UIAxes2,'left')
                plot(app.UIAxes2, xData1, yData1, '-', 'Color','r', 'LineWidth',2)
                hold(app.UIAxes2,'on')
                scatter(app.UIAxes2, xData2, yData2,10, 'o','MarkerFaceColor','k')
                ylim(app.UIAxes2, [0 inf])
                set(app.UIAxes2,'yscale','linear','Box','on','YColor','r' );
                ylabel(app.UIAxes2, [ParName,' SP^2'],'FontWeight','bold')
                xlabel(app.UIAxes2,'Voltage','FontWeight','bold')
                top = 200*ceil(max(xData1)/200);
                
                if top > 1500
                    xticks(app.UIAxes2,[0:200:top])
                else
                    xticks(app.UIAxes2,[0:100:top])
                end
                xtickangle(app.UIAxes2,90)
                xlim(app.UIAxes2,[0 top])
                
                % Spe plotting
                yyaxis(app.UIAxes2,'right')
                plot(app.UIAxes2, xData1, yData3, '-', 'Color','b', 'LineWidth',2)
                hold(app.UIAxes2,'on')
                scatter(app.UIAxes2, xData2, yData4,10, 'o','MarkerFaceColor','k')
                set(app.UIAxes2,'YColor','b','yscale','log')
                %                 ylabel(app.UIAxes2, [ParName,' Spe'],'FontWeight','bold')
                ylabel(app.UIAxes2, [ParName,' SD^2'],'FontWeight','bold')
                if min(yData1) == max(yData1)
                    title(app.UIAxes2,['Indeterminable'])
                else
                    line(app.UIAxes2, [OptVolt OptVolt],[1 max(app.UIAxes2.YLim)],'Color','k','LineWidth',2,'LineStyle',':')
                    title(app.UIAxes2, ['Min Voltage = ', num2str(OptVolt)])
                end
                MaxVtSliderValueChanged(app, @MaxVtSliderValueChanged)
            end
            
        end
        
        function QbStatistcs(app)
            Data.Name.ParNames = app.ParameterDropDown.Items;
            Data.Name.TestCond = app.Test_Condition;
            Data.Name.LaserStat = app.Laser_State;
            Data.mean = app.Stat_Median;
            Data.SD = app.Stat_SD;
            Data.Vt = app.ParVt;
        end
        
        
        function IndexDefaults(app)
            
            % Get filepath
            filepath = fullfile(getpref('QbPASS','FileDirectory'));
            % Get a list of all files and folders in this folder.
            files = dir(filepath);
            % Get a logical vector that tells which is a directory.
            dirFlags = [files.isdir];
            % Extract only those that are directories.
            Folders = files(dirFlags);
            Folders = {Folders.name};
            Folders = Folders(~contains(Folders,{'.','..'}));
            if isempty(Folders)
            else
                app.CytometersListBox.Items = Folders;
                app.CytometersListBox.ItemsData = 1:numel(Folders);
            end
        end


        function DatasetGUIUpdate(app)
            
            app.MaxVtSlider.Limits = [min(app.ParVt(:)) max(app.ParVt(:))];
            app.MaxVtSlider.Value = max(app.ParVt(:));
            
            % create dropdown selection items
            app.TestConditionDropDown.Items = unique(app.Test_Condition, 'stable');
            
            UnqParNames = unique(app.ParNames, 'stable')';
            app.ParameterDropDown.Items = UnqParNames;
            DropDownSelection(app)
            
            ParInd = not(contains(UnqParNames(1,:), getpref('QbPASS','ExclusionParameters')));
            
            app.ParameterDropDown_2.Items = UnqParNames(ParInd);
            app.ParameterDropDown_2.ItemsData = 1:numel(UnqParNames(ParInd));
            
            app.UITable2.Data = [app.ParameterDropDown.Items(ParInd)', num2cell(zeros(numel(app.ParameterDropDown.Items(ParInd)),4))];
            
            % update voltage slider for table filtering
            app.MinVoltageSlider.Limits = [min(min(app.ParVt)) max(max(app.ParVt))];
            app.MinVoltageSlider.Value = min(min(app.ParVt));
            
            
        end
        
        function WarnUser(app, errorMessage)
            app.Qb.Visible = 'on';
            
            % Alert user via the command window and a popup message.
            uialert(app.Qb, errorMessage,'Warning','Icon','warning');
            
            % Open the Error Log file for appending.
            fullFileName = fullfile(getpref('QbPASS','FileDirectory'),'QbPASS Error Log.txt');
            if exist(getpref('QbPASS','FileDirectory'), 'dir')
                
                [fid] = fopen(fullFileName, 'at');
                fprintf(fid, '%s\n', '%%%%%%%%%%%%%%%%%%%%%%%%%%%%'); % To file
                fprintf(fid, '%s\n', datetime('now')); % To file
                fprintf(fid, '\n'); % To file
                fprintf(fid, '%s\n', errorMessage); % To file
                fprintf(fid, '\n'); % To file
                
                fclose(fid);
                return; % from WarnUser()
            else
                
            end
        end
        
        function LoadGUIVars(app)
            app.CytometerEditField.Value = char(app.LoadedDataset.Cyt);
            app.DateAcquiredDatePicker.Value = app.LoadedDataset.AcqDate(1);
            
            app.ParNames = app.LoadedDataset.ParNames;
            app.Stat_SD = app.LoadedDataset.SD;
            app.Stat_CV = app.LoadedDataset.CV;
            app.Stat_Median = app.LoadedDataset.Med;
            app.ParVt = app.LoadedDataset.Vt;
            app.Laser_State = app.LoadedDataset.LaserState;
            app.Test_Condition = app.LoadedDataset.TestCond;
            app.Pulser_Intensity = app.LoadedDataset.PulserInt;
            app.ImportSelpath = app.LoadedDataset.fcsdir;
            
        end
        function LoadGUIVarsFile(app, Database)
            app.CytometerEditField.Value = char(app.LoadedDataset.Cyt);
            app.DateAcquiredDatePicker.Value = app.LoadedDataset.AcqDate(1);
            
            app.ParNames = app.LoadedDataset.ParNames;
            app.Stat_SD = app.LoadedDataset.SD;
            app.Stat_CV = app.LoadedDataset.CV;
            app.Stat_Median = app.LoadedDataset.Med;
            app.ParVt = app.LoadedDataset.Vt;
            app.Laser_State = app.LoadedDataset.LaserState;
            app.Test_Condition = app.LoadedDataset.TestCond;
            app.Pulser_Intensity = app.LoadedDataset.PulserInt;
            app.ImportSelpath = app.LoadedDataset.fcsdir;
            
            app.Summary = Database.Summary;
            app.OptiStats = Database.OptiStats;
            app.VtTNames = Database.VoltTNames;
            app.VtTData = Database.VoltTData;
            
            app.PhiThresholdEditField.Value = app.LoadedDataset.PhiThreshold;
            
        end
        
        function UpdateVoltrationTable(app)
            
            % update summary voltage table
            app.UITable2.Data = cell2table([app.OptiStats.ParName(:),...
                num2cell([app.OptiStats.VtOpt(:),...
                app.OptiStats.ERF(:),...
                app.OptiStats.Channel(:),...
                app.OptiStats.ERFvt(:)])]);
        end
        
        function UpdateSummaryTable(app)
            
            ColNames = {'Parameter',...
                'Min Vt',...
                'ERF Vt',...
                'µ F',...
                'Sigma F',...
                'µ B',...
                'Sigma B',...
                'ERF units',...
                'ERF channel',...
                'Bead Spe',...
                'SP^2',...
                'Spe',...
                'Phi',...
                'Mu F0 + Mu B0 (Spe/Channel)',...
                'Mu F0 (S_Spe)',...
                'Mu F0 + Mu B0 (S_ERF)',...
                'Mu F0 Spe',...
                'Mu F0',...
                'Mu B0 Channel',...
                'Mu B0 Spe',...
                'Q Spe',...
                'Mean B Channel',...
                'Mean B Spe',...
                'Spe @ max channel',...
                'Dy Range (Spe)',...
                'Dy Range (dB)'};
            
            PNames  = app.OptiStats.ParName(:);
            Vt      = strsplit(num2str(app.OptiStats.VtOpt(:)'))';
            ERFvt   = strsplit(sprintf('%g ', real(round(app.OptiStats.ERFvt(:), 2))))';
            MuFMed  = strsplit(sprintf('%g ', real(round(app.OptiStats.MuFMed(:), 2))))';
            MuFSD   = strsplit(sprintf('%g ', real(round(app.OptiStats.MuFSD(:), 2))))';
            MuBMed  = strsplit(sprintf('%g ', real(round(app.OptiStats.MuBMed(:), 2))))';
            MuBSD   = strsplit(sprintf('%g ', real(round(app.OptiStats.MuBSD(:), 2))))';
            ERF     = strsplit(sprintf('%g ', real(round(app.OptiStats.ERF(:), 0))))';
            Channel = strsplit(sprintf('%g ', real(round(app.OptiStats.Channel(:), 2))))';
            BeadSpe = strsplit(sprintf('%g ', real(round(app.OptiStats.BeadSpe(:), 2))))';
            SP2     = strsplit(sprintf('%g ', real(round(app.OptiStats.SP2(:), 2))))';
            Spe     = strsplit(sprintf('%g ', real(round(app.OptiStats.Spe(:), 2))))';
            Phi     = strsplit(sprintf('%g ', real(round(app.OptiStats.Phi(:), 2))))';
            F0B0    = strsplit(sprintf('%g ', real(round(app.OptiStats.F0B0(:), 2))))';
            Sspe    = strsplit(sprintf('%g ', real(round(app.OptiStats.Sspe(:), 2))))';
            S_ERF   = strsplit(sprintf('%g ', real(round(app.OptiStats.S_ERF(:), 2))))';
            MuFoSpe = strsplit(sprintf('%g ', real(round(app.OptiStats.MuFoSpe(:), 2))))';
            MuFo    = strsplit(sprintf('%g ', real(round(app.OptiStats.MuFo(:), 2))))';
            MuBo    = strsplit(sprintf('%g ', real(round(app.OptiStats.MuBo(:), 2))))';
            MuBoSpe = strsplit(sprintf('%g ', real(round(app.OptiStats.MuBoSpe(:), 2))))';
            QSpe    = strsplit(sprintf('%g ', real(round(app.OptiStats.QSpe(:), 2))))';
            Btot    = strsplit(sprintf('%g ', real(round(app.OptiStats.Btot(:), 2))))';
            Bspe    = strsplit(sprintf('%g ', real(round(app.OptiStats.Bspe(:), 2))))';
            MChSpe  = strsplit(sprintf('%g ', real(round(app.OptiStats.MChSpe(:)',0))))';
            DyRSpe  = strsplit(sprintf('%g ', real(round(app.OptiStats.DyRSpe(:)',0))))';
            DyRDb  =  strsplit(sprintf('%g ', real(round(app.OptiStats.DyRDb(:)',2))))';
            
            ind = 1:numel(DyRDb)-1;
            Datset = horzcat(ERFvt(ind), MuFMed(ind),...
                MuFSD(ind), MuBMed(ind), MuBSD(ind), ERF(ind),...
                Channel(ind), BeadSpe(ind), SP2(ind),Spe(ind),...
                Phi(ind), F0B0(ind), Sspe(ind), S_ERF(ind), MuFoSpe(ind),...
                MuFo(ind), MuBo(ind), MuBoSpe(ind), QSpe(ind), Btot(ind),...
                Bspe(ind), MChSpe(ind), DyRSpe(ind), DyRDb(ind));
            
            
            Data = horzcat(PNames, Vt, Datset);
            
            app.UITable3.Data = Data;
            app.UITable3.ColumnName = ColNames;
            app.UITable3.FontSize = 14;
        end
        
        function results = loadImages(app)
            app.AddCytometer.Icon = 'add.png';
            app.RemoveCytometer.Icon = 'remove.png';
            app.AddDataset.Icon = 'add.png';
            app.RemoveDataset.Icon = 'remove.png';
            app.Image.ImageSource = 'Qb Logo.jpg';
        end
        
        function CheckPhiThresholdSet(app)
            
            if isfield(app.LoadedDataset,'PhiThreshold')
                
            else
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Version = 1.22;
            try
                QbPASS_Preference_Check_v2(app.Version, app.Qb);
                IndexDefaults(app)
                loadImages(app)
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
        end

        % Callback function
        function ImportfilesMenuSelected(app, event)
            
            % clear all app variables before import of new variables
            ClearAppVars(app)
            
            % get file directory path
            app.ImportSelpath = uigetdir();
            
            if length(app.ImportSelpath) <= 1
            else
                
                % get filenames from directory
                Files = dir([app.ImportSelpath,'/*.fcs']);
                
                % add filenames to GUI
                app.FilenamesListBox.Items = {Files.name};
                
                % create string to import files
                Filenames = strcat({Files.folder},{'/'},{Files.name});
                
                % give error message if no fcs files are in the directory
                if numel(Filenames) == 0
                    uialert(app.Qb,'No .fcs files found in folder','Invalid Folder');
                else
                    
                    [app, Dataset, d, var] = QbPASS_ProcessFiles(app, Filenames);
                    
                    [Dataset.(var)]=QbPASS_Database_ImportQC(Dataset.(var));
                    
                    app.LoadedDataset = Dataset.(var);
                    
                    LoadGUIVars(app)
                    DatasetGUIUpdate(app)
                    
                    QbPASS_ERFTemplateImporter(app, 'Automatic')  % determine whether an ERF template is present in fcs folder
                    
                    [app.TData, app.Summary, app.OptiStats] = QbPASS_OptimalDataset_v5(app.LoadedDataset, ...
                        app.MinVoltageSlider.Value, ...
                        'off', ...
                        app.UITable2.Data,...
                        app.MaxVtSlider.Value,...
                        app.PhiThresholdEditField.Value);
                    
                    UpdateSummaryTable(app) % update  statistics table
                    % plot selected graph data
                    PlotGraph(app)
                    PlotExtractedData(app)
                    
                    QbPASS_SaveDataset(app)
                    IndexDefaults(app)
                    CytometersListBoxValueChanged(app, @CytometersListBoxValueChanged)
                    close(d)
                end
            end
        end

        % Value changed function: LaserStatusDropDown
        function LaserStatusDropDownValueChanged(app, event)
            PlotGraph(app)
        end

        % Value changed function: ParameterDropDown
        function ParameterDropDownValueChanged(app, event)
            PlotGraph(app)
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            PlotGraph(app)
        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            PlotGraph(app)
        end

        % Menu selected function: DatasettoxlsMenu
        function ExportMenuSelected(app, event)
            d = uiprogressdlg(app.Qb,'Title','Please Wait',...
                'Message','Processing file data','Indeterminate','on');
            try
                QbPASS_OptimalDataset_v5(app.LoadedDataset, ...
                    app.MinVoltageSlider.Value, ...
                    'yes', ...
                    app.UITable2.Data,...
                    app.MaxVtSlider.Value,...
                    app.PhiThresholdEditField.Value);
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
            close(d)
        end

        % Value changed function: TestConditionDropDown
        function TestConditionDropDownValueChanged(app, event)
            DropDownSelection(app)
            PlotGraph(app)
        end

        % Value changed function: PulserIntensityDropDown
        function PulserIntensityDropDownValueChanged(app, event)
            PlotGraph(app)
        end

        % Value changed function: MinVoltageSlider
        function MinVoltageSliderValueChanged(app, event)
            ProcessDatabase(app,'no')
        end

        % Value changed function: LogYAxisCheckBox
        function LogYAxisCheckBoxValueChanged(app, event)
            UIAxisDataScaling(app)
        end

        % Value changed function: LogXAxisCheckBox
        function LogXAxisCheckBoxValueChanged(app, event)
            UIAxisDataScaling(app)
        end

        % Button pushed function: UpdateStatisticsButton
        function UpdateStatisticsButtonPushed(app, event)
            
            d = uiprogressdlg(app.Qb,'Title','Please Wait',...
                'Message','Processing file data','Indeterminate','on');
            
            try
                QbPASS_ERFTemplateImporter(app, 'Automatic')  % determine whether an ERF template is present in fcs folder
                
                [app.TData, app.Summary, app.OptiStats] = QbPASS_OptimalDataset_v5(app.LoadedDataset, ...
                    app.MinVoltageSlider.Value, ...
                    'off', ...
                    app.UITable2.Data,...
                    app.MaxVtSlider.Value,...
                    app.PhiThresholdEditField.Value);
                
                UpdateSummaryTable(app) % update  statistics table
                % plot selected graph data
                PlotGraph(app)
                PlotExtractedData(app)
                
                QbPASS_SaveDataset(app)
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
            close(d) % close progress bar
            
            
        end

        % Menu selected function: testMenu
        function testMenuSelected(app, event)
            clc
            % PrintTable with "row header" mode
            t = PrintTable('This is PrintTable-RowHeader-Caption test, created on %s',datestr(now));
            t.HasRowHeader = true;
            t.HasHeader = true;
            ColNames = [app.UITable2.ColumnName{:}];
            t.addRow(['header-autofmt',ColNames]);
            for i = 1:size(app.UITable2.Data,1)
                data = [app.UITable2.Data(i,:)];
                t.addRow(data);
            end
            
            t.display;
        end

        % Value changed function: ParameterDropDown_2
        function ParameterDropDown_2ValueChanged(app, event)
            PlotExtractedData(app)
        end

        % Menu selected function: ERFtemplateMenuExport
        function ERFtemplateMenuExportSelected(app, event)
            if isempty(app.UITable2.Data)
            else
                try
                    TimeStamp = replace(datestr(datetime('now')), ':','-');
                    DateStr = ['ERF Template ',TimeStamp,'.xls'];
                    [file,path] = uiputfile('.xls', 'Save Output File', DateStr);
                    OutputStr = fullfile(path, file);
                    
                    Names = app.UITable2.ColumnName([1 3 4])';
                    Data = table2cell(app.UITable2.Data(:,[1 3 4]));
                    writecell([Names;Data],OutputStr,'Sheet','ERF Template')
                catch
                    uialert(app.Qb,'Export ERF Template','Error exporting ERF Template');
                end
            end
        end

        % Menu selected function: ERFtemplateMenuImport
        function ERFtemplateMenuImportSelected(app, event)
            d = uiprogressdlg(app.Qb,'Title','Please Wait',...
                'Message','Processing file data','Indeterminate','on');
            
            try
                QbPASS_ERFTemplateImporter(app, 'User')  % determine whether an ERF template is present in fcs folder
                
                [app.TData, app.Summary, app.OptiStats] = QbPASS_OptimalDataset_v5(app.LoadedDataset, ...
                    app.MinVoltageSlider.Value, ...
                    'off', ...
                    app.UITable2.Data,...
                    app.MaxVtSlider.Value,...
                    app.PhiThresholdEditField.Value);
                
                UpdateSummaryTable(app) % update  statistics table
                % plot selected graph data
                PlotGraph(app)
                PlotExtractedData(app)
                
                QbPASS_SaveDataset(app)
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
            close(d) % close progress bar
        end

        % Value changed function: AutoPlotDropDown
        function AutoPlotDropDownValueChanged(app, event)
            try
                switch app.AutoPlotDropDown.Value
                    case 'yes'
                        VoltrationPlotButtonPushed(app, @VoltrationPlotsButtonPushed)
                end
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
            
        end

        % Value changed function: MaxVtSlider
        function MaxVtSliderValueChanged(app, event)
            try
                app.UIAxes2.XLim = [0 app.MaxVtSlider.Value];
                set(app.UIAxes2,'XTickMode','auto')
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
        end

        % Button pushed function: QCPlotButton
        function QCPlotButtonPushed(app, event)
            try
                switch app.QCPlotDropDown.Value
                    case 'Background'
                        Name.ParNames = app.ParameterDropDown.Items;
                        Name.TestCond = app.Test_Condition;
                        Name.LaserStat = app.Laser_State;
                        Name.LED = app.Pulser_Intensity;
                        Data.SD = app.Stat_SD;
                        Data.CV = app.Stat_CV;
                        Data.Vt = app.ParVt;
                        Data.Med = app.Stat_Median;
                        Data.Processed = app.Summary;
                        d = uiprogressdlg(app.Qb,'Title','Please Wait',...
                            'Message','Plotting data','Indeterminate','on');
                        [fig]=QbPASS_QC_Plot_v1(Data, Name,'QC',app.LoadedDataset);
                        close(d)
                    case 'Normalized'
                        Name.ParNames = app.ParameterDropDown.Items;
                        Name.TestCond = app.Test_Condition;
                        Name.LaserStat = app.Laser_State;
                        Name.LED = app.Pulser_Intensity;
                        Data.SD = app.Stat_SD;
                        Data.CV = app.Stat_CV;
                        Data.Vt = app.ParVt;
                        Data.Med = app.Stat_Median;
                        Data.Range = app.Range;
                        d = uiprogressdlg(app.Qb,'Title','Please Wait',...
                            'Message','Plotting data','Indeterminate','on');
                        [fig]=QbPASS_QC_Plot_v1(Data, Name,'Signal',app.LoadedDataset);
                        close(d)
                    case 'Area Scaling'
                        Name.ParNames = app.ParameterDropDown.Items;
                        Name.TestCond = app.Test_Condition;
                        Name.LaserStat = app.Laser_State;
                        Name.LED = app.Pulser_Intensity;
                        Data.SD = app.Stat_SD;
                        Data.CV = app.Stat_CV;
                        Data.Vt = app.ParVt;
                        Data.Med = app.Stat_Median;
                        Data.Range = app.Range;
                        
                        if max(contains(app.LoadedDataset.ParNames(1,:),'-H')) == 1
                            [fig]=QbPASS_QC_Plot_v1(Data, Name,'Area Scaling',app.LoadedDataset);
                            
                        else
                            uialert(app.Qb,'Dataset does not contain -H parameters','Error generating Area Scaling QC Plot');
                            
                        end
                        
                end
                fig.Visible = 'on';
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
        end

        % Menu selected function: About
        function AboutMenuSelected(app, event)
            Master_QbAbout()
        end

        % Button pushed function: AddCytometer
        function AddCytometerPushed(app, event)
            %             try
            prompt = {'Enter unique cytometer name:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'e.g. Fortessa X-20 A'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            waitfor(answer);
            ind = numel(app.CytometersListBox.Items);
            error = 0;
            
            for i = 1:ind
                if strcmp(answer, app.CytometersListBox.Items{i}) == 1
                    error = 1;
                end
            end
            
            if error ==1
                uialert(app.Qb,'Cytometer name already exists', 'Invalid name')
            else
                path = char(getpref('QbPASS','FileDirectory'));
                name = char(answer{1});
                filename = char(fullfile(path,name));
                result = isfolder(filename);
                if result == 1
                    uialert(app.Qb,'Cytometer name already exists', 'Invalid name')
                else
                    mkdir(filename)
                    app.CytometersListBox.Items{ind+1} = answer{1};
                    app.CytometersListBox.ItemsData = 1:numel(app.CytometersListBox.Items);
                end
                
            end
            
            %             catch ME
            %                 errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
            %                     ME.stack(1).name, ME.stack(1).line, ME.message);
            %                 WarnUser(app, errorMessage);
            %             end
            
        end

        % Button pushed function: RemoveCytometer
        function RemoveCytometerButtonPushed(app, event)
            try
                if numel(app.CytometersListBox.Items) == 0
                    
                    uialert(app.Qb, 'No cytometers exist to remove','Error adding dataset')
                elseif isempty(app.CytometersListBox.Value)
                    uialert(app.Qb, 'Select a cytometer database to remove','Error adding dataset')
                else
                    answer = uiconfirm(app.Qb,...
                        ['Are you sure wish to remove the ', app.CytometersListBox.Items{app.CytometersListBox.Value},' database?'], ...
                        'Confirm database deletion','options',{'Yes', 'Cancel'},'Icon','warning');
                    switch answer
                        case 'Yes'
                            CytName = app.CytometersListBox.Items{app.CytometersListBox.Value};
                            path = char(getpref('QbPASS','FileDirectory'));
                            name = char([CytName]);
                            filename = char(fullfile(path,name));
                            rmdir(filename,'s');
                            
                            app.CytometersListBox.Items(app.CytometersListBox.Value) = [];
                            app.DatasetsListBox.Items(1:end) = [];
                            if isempty(app.CytometersListBox.Items)
                            else
                                app.CytometersListBox.ItemsData = 1:numel(app.CytometersListBox.Items);
                            end
                    end
                    CytometersListBoxValueChanged(app, @CytometersListBoxValueChanged)
                end
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
        end

        % Button pushed function: AddDataset
        function AddDatasetButtonPushed(app, event)
            %             try
            if numel(app.CytometersListBox.Items) == 0
                uialert(app.Qb, 'No cytometers added','Error adding dataset')
            elseif isempty(app.CytometersListBox.Value)
                uialert(app.Qb, 'Select cytometer to add dataset to','Error adding dataset')
            else
                ImportfilesMenuSelected(app,@ImportfilesMenuSelected)
                
            end
            %             catch ME
            %                 errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
            %                     ME.stack(1).name, ME.stack(1).line, ME.message);
            %                 WarnUser(app, errorMessage);
            %             end
        end

        % Button pushed function: RemoveDataset
        function RemoveDatasetButtonPushed(app, event)
            try
                if numel(app.DatasetsListBox.Items) == 0
                    
                    uialert(app.Qb, 'No cytometer datasets exist to remove','Error adding dataset')
                elseif isempty(app.DatasetsListBox.Value)
                    uialert(app.Qb, 'Select a dataset to remove','Error adding dataset')
                else
                    answer = uiconfirm(app.Qb,...
                        ['Are you sure wish to remove the ', app.DatasetsListBox.Items{app.DatasetsListBox.Value},' database?'], ...
                        'Confirm database deletion','options',{'Yes', 'Cancel'},'Icon','warning');
                    switch answer
                        case 'Yes'
                            CytName = app.CytometersListBox.Items{app.CytometersListBox.Value};
                            path = char(getpref('QbPASS','FileDirectory'));
                            name = char([CytName,' Database.mat']);
                            filename = char(fullfile(path,name));
                            Dataset = app.DatasetsListBox.Items{app.DatasetsListBox.Value};
                            ID = ['D_',num2str(datenum(Dataset))];
                            tmp = rmfield(load(filename), ID);
                            save(filename, '-struct', 'tmp');
                            
                            IndexDefaults(app)
                    end
                end
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
        end

        % Menu selected function: PreferencesMenu
        function PreferencesMenuSelected(app, event)
            Master_Pref_v1(app.Version)
        end

        % Value changed function: CytometersListBox
        function CytometersListBoxValueChanged(app, event)
            try
                filepath = fullfile(getpref('QbPASS','FileDirectory'),app.CytometersListBox.Items{app.CytometersListBox.Value},'*.mat');
                files = dir(filepath);
                filenames = {files.name};
                ind = contains(filenames,'D_');
                filenames = filenames(ind);
                
                if isempty(filenames)
                    app.DatasetsListBox.Items(1:end) =[];
                else
                    DispNames = cellstr(datestr(str2double(replace(filenames,{'D_','.mat'},'')), 'dd mmm yyyy'));
                    app.DatasetsListBox.Items = DispNames;
                    app.DatasetsListBox.ItemsData = 1:numel(DispNames);
                    if isempty(app.DatasetsListBox.Value)
                        app.DatasetsListBox.Value = 1;
                    end
                end
                DatasetsListBoxValueChanged(app, @DatasetsListBoxValueChanged)
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
        end

        % Value changed function: DatasetsListBox
        function DatasetsListBoxValueChanged(app, event)
            try
                d = uiprogressdlg(app.Qb,'Title','Please Wait',...
                    'Message','Loading data from file','Indeterminate','on');
                
                ClearAppVars(app)
                
                if isempty(app.DatasetsListBox.Items)
                else
                    
                    Dataset = app.DatasetsListBox.Items{app.DatasetsListBox.Value};
                    ID = ['D_',num2str(datenum(Dataset)),'.mat'];
                    
                    path = char(getpref('QbPASS','FileDirectory'));
                    folder = app.CytometersListBox.Items{app.CytometersListBox.Value};
                    name = ID;
                    filename = char(fullfile(path,folder,name));
                    
                    TrDataset = load(filename,'Database');
                    app.LoadedDataset = TrDataset.Database;
                    
                    LoadGUIVarsFile(app, TrDataset.Database)
                    DatasetGUIUpdate(app)
                    
                    CheckPhiThresholdSet(app)
                    % plot selected graph data
                    PlotGraph(app)
                    PlotExtractedData(app)
                    
                    UpdateVoltrationTable(app)
                    UpdateSummaryTable(app)
                    close(d)
                    
                    %                     UpdateStatisticsButtonPushed(app, @UpdateButtonPushed)
                end
                if exist('d','var') == 1
                    close(d)
                end
                
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
        end

        % Button pushed function: VoltrationPlotButton
        function VoltrationPlotButtonPushed(app, event)
            try
                if isempty(app.Summary)
                    uialert(app.Qb,'Dataset not present','Databset error')
                else
                    d = uiprogressdlg(app.Qb,'Title','Please Wait',...
                        'Message','Plotting data','Indeterminate','on');
                    [fig]=QbPASS_PlotData_v2(app);
                    fig.Visible = 'on';
                    
                    close(d)
                end
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
        end

        % Menu selected function: OnlineResourcesMenu
        function OnlineResourcesMenuSelected(app, event)
            url = 'http://10.208.6.222/';
            web(url, '-browser')
        end

        % Button pushed function: TrackingPlotButton
        function TrackingPlotButtonPushed(app, event)
            try
                for i = 1:numel(app.DatasetsListBox.Items)
                    Dataset = app.DatasetsListBox.Items{i};
                    ID = ['D_',num2str(datenum(Dataset))];
                    
                    path = char(getpref('QbPASS','FileDirectory'));
                    name = char([app.CytometersListBox.Items{app.CytometersListBox.Value},' Database.mat']);
                    filename = char(fullfile(path,name));
                    
                    TrDataset = load(filename,ID);
                    Data{i} = TrDataset.(ID);
                end
                
                QbPASS_PerformanceTracking(Data)
            catch ME
                errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
                    ME.stack(1).name, ME.stack(1).line, ME.message);
                WarnUser(app, errorMessage);
            end
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
%             try
                [file, path] = uiputfile('*.pdf','Select export location',['Output Report ',replace(datestr(datetime('now')),':','-')]);
                if isempty(file)
                else
                
                filename = fullfile(path, file);
                
                %% background plot
                Name.ParNames = app.ParameterDropDown.Items;
                Name.TestCond = app.Test_Condition;
                Name.LaserStat = app.Laser_State;
                Name.LED = app.Pulser_Intensity;
                Data.SD = app.Stat_SD;
                Data.CV = app.Stat_CV;
                Data.Vt = app.ParVt;
                Data.Med = app.Stat_Median;
                Data.Processed = app.Summary;
                d = uiprogressdlg(app.Qb,'Title','Please Wait',...
                    'Message','Plotting data','Indeterminate','on');
                [fig]=QbPASS_QC_Plot_v1(Data, Name,'QC',app.LoadedDataset);
                
                fig.PaperOrientation = 'landscape';
                fig.PaperSize = [20 14.14];
                fig.PaperUnits = 'normalized';
                fig.PaperPosition = [0 0 1 1];
                
                % voltration plot
                print(fig, '-append', '-dpsc2', [filename(1:end-3),'ps']);
                [fig]=QbPASS_PlotData_v2(app);
                
                fig.PaperOrientation = 'landscape';
                fig.PaperSize = [20 14.14];
                fig.PaperUnits = 'normalized';
                fig.PaperPosition = [0 0 1 1];
                print(fig, '-append', '-dpsc2', [filename(1:end-3),'ps']);
                end
                %             % table
                %             print(app.UITable2, '-append', '-dpsc2', [filename(1:end-3),'ps']);
                %             close(fig)
%             catch ME
%                 errorMessage = sprintf('Error %s() %d.\n\nError Message:\n%s', ...
%                     ME.stack(1).name, ME.stack(1).line, ME.message);
%                 WarnUser(app, errorMessage);
%                 
%             end
%             close(d)
        end

        % Value changed function: VoltrationPlotDropDown
        function VoltrationPlotDropDownValueChanged(app, event)
            switch app.VoltrationPlotDropDown.Value
                case 'SD^2'
                    setpref('QbPASS', 'VoltPlotStat', 'SD^2')
                    
                case 'Spe'
                    setpref('QbPASS', 'VoltPlotStat', 'Spe')
                    
            end
            
        end

        % Menu selected function: NewDirectoryMenu
        function UpdatedirectoryMenuSelected(app, event)
            selpath = uigetdir;
            if selpath == 0
            else
                setpref('QbPASS','FileDirectory',selpath)
                IndexDefaults(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Qb = uifigure('Visible', 'off');
            app.Qb.Name = 'QbPASS';
            app.Menu = uimenu(app.Qb);
            app.Menu.Text = 'Menu';
            app.About = uimenu(app.Menu);
            app.About.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.About.Text = 'About';
            app.PreferencesMenu = uimenu(app.Menu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferencesMenuSelected, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.ImportfilesMenu = uimenu(app.Menu);
            app.ImportfilesMenu.Text = 'Import files';
            app.ERFtemplateMenuImport = uimenu(app.ImportfilesMenu);
            app.ERFtemplateMenuImport.MenuSelectedFcn = createCallbackFcn(app, @ERFtemplateMenuImportSelected, true);
            app.ERFtemplateMenuImport.Text = 'ERF template';
            app.NewDirectoryMenu = uimenu(app.ImportfilesMenu);
            app.NewDirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @UpdatedirectoryMenuSelected, true);
            app.NewDirectoryMenu.Text = 'New Directory';
            app.ExportMenu = uimenu(app.Menu);
            app.ExportMenu.Text = 'Export';
            app.ERFtemplateMenuExport = uimenu(app.ExportMenu);
            app.ERFtemplateMenuExport.MenuSelectedFcn = createCallbackFcn(app, @ERFtemplateMenuExportSelected, true);
            app.ERFtemplateMenuExport.Text = 'ERF template';
            app.DatasettoxlsMenu = uimenu(app.ExportMenu);
            app.DatasettoxlsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.DatasettoxlsMenu.Text = 'Dataset to .xls';
            app.OnlineResourcesMenu = uimenu(app.Menu);
            app.OnlineResourcesMenu.MenuSelectedFcn = createCallbackFcn(app, @OnlineResourcesMenuSelected, true);
            app.OnlineResourcesMenu.Text = 'Online Resources';
            app.testMenu = uimenu(app.Qb);
            app.testMenu.MenuSelectedFcn = createCallbackFcn(app, @testMenuSelected, true);
            app.testMenu.Visible = 'off';
            app.testMenu.Text = 'test';
            app.TabGroup = uitabgroup(app.Qb);
            app.InstrumentsTab = uitab(app.TabGroup);
            app.InstrumentsTab.Title = 'Instruments';
            app.CytometersListBoxLabel = uilabel(app.InstrumentsTab);
            app.CytometersListBoxLabel.Text = 'Cytometer IDs';
            app.CytometersListBox = uilistbox(app.InstrumentsTab);
            app.CytometersListBox.Items = {};
            app.CytometersListBox.ValueChangedFcn = createCallbackFcn(app, @CytometersListBoxValueChanged, true);
            app.CytometersListBox.Value = {};
            app.AddCytometer = uibutton(app.InstrumentsTab, 'push');
            app.AddCytometer.ButtonPushedFcn = createCallbackFcn(app, @AddCytometerPushed, true);
            app.AddCytometer.Icon = 'add.png';
            app.AddCytometer.Tooltip = {'add cytometer'};
            app.AddCytometer.Text = '';
            app.DatasetsListBoxLabel = uilabel(app.InstrumentsTab);
            app.DatasetsListBoxLabel.Text = 'Datasets';
            app.DatasetsListBox = uilistbox(app.InstrumentsTab);
            app.DatasetsListBox.Items = {};
            app.DatasetsListBox.ValueChangedFcn = createCallbackFcn(app, @DatasetsListBoxValueChanged, true);
            app.DatasetsListBox.Value = {};
            app.AddDataset = uibutton(app.InstrumentsTab, 'push');
            app.AddDataset.ButtonPushedFcn = createCallbackFcn(app, @AddDatasetButtonPushed, true);
            app.AddDataset.Icon = 'add.png';
            app.AddDataset.Tooltip = {'add dataset'};
            app.AddDataset.Text = '';
            app.CytometerEditFieldLabel = uilabel(app.InstrumentsTab);
            app.CytometerEditFieldLabel.Text = 'Cytometer';
            app.CytometerEditField = uieditfield(app.InstrumentsTab, 'text');
            app.CytometerEditField.Editable = 'off';
            app.DateAcquiredDatePickerLabel = uilabel(app.InstrumentsTab);
            app.DateAcquiredDatePickerLabel.Text = 'Date Acquired';
            app.DateAcquiredDatePicker = uidatepicker(app.InstrumentsTab);
            app.DateAcquiredDatePicker.Editable = 'off';
            app.RemoveCytometer = uibutton(app.InstrumentsTab, 'push');
            app.RemoveCytometer.ButtonPushedFcn = createCallbackFcn(app, @RemoveCytometerButtonPushed, true);
            app.RemoveCytometer.Icon = 'remove.png';
            app.RemoveCytometer.Tooltip = {'add cytometer'};
            app.RemoveCytometer.Text = {''; ''};
            app.RemoveDataset = uibutton(app.InstrumentsTab, 'push');
            app.RemoveDataset.ButtonPushedFcn = createCallbackFcn(app, @RemoveDatasetButtonPushed, true);
            app.RemoveDataset.Icon = 'remove.png';
            app.RemoveDataset.Tooltip = {'add cytometer'};
            app.RemoveDataset.Text = {''; ''};
            app.Image = uiimage(app.InstrumentsTab);
            app.Image.ImageSource = 'QbLogo.png';
            app.QCPlotButton = uibutton(app.InstrumentsTab, 'push');
            app.QCPlotButton.ButtonPushedFcn = createCallbackFcn(app, @QCPlotButtonPushed, true);
            app.QCPlotButton.Text = 'QC Plot';
            app.AutoPlotDropDownLabel = uilabel(app.InstrumentsTab);
            app.AutoPlotDropDownLabel.Text = 'Auto Plot';
            app.AutoPlotDropDown = uidropdown(app.InstrumentsTab);
            app.AutoPlotDropDown.Items = {'yes', 'no'};
            app.AutoPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @AutoPlotDropDownValueChanged, true);
            app.AutoPlotDropDown.Tooltip = {'Plot voltration curves on dataset loading'};
            app.AutoPlotDropDown.Value = 'no';
            app.VoltrationPlotButton = uibutton(app.InstrumentsTab, 'push');
            app.VoltrationPlotButton.ButtonPushedFcn = createCallbackFcn(app, @VoltrationPlotButtonPushed, true);
            app.VoltrationPlotButton.Text = 'Voltration Plot';
            app.TrackingPlotButton = uibutton(app.InstrumentsTab, 'push');
            app.TrackingPlotButton.ButtonPushedFcn = createCallbackFcn(app, @TrackingPlotButtonPushed, true);
            app.TrackingPlotButton.Text = 'Tracking Plot';
            app.GenerateReportButton = uibutton(app.InstrumentsTab, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.QCPlotDropDown = uidropdown(app.InstrumentsTab);
            app.QCPlotDropDown.Items = {'Background', 'Normalized', 'Area Scaling'};
            app.QCPlotDropDown.Value = 'Background';
            app.VoltrationPlotDropDown = uidropdown(app.InstrumentsTab);
            app.VoltrationPlotDropDown.Items = {'SD^2', 'Spe'};
            app.VoltrationPlotDropDown.ValueChangedFcn = createCallbackFcn(app, @VoltrationPlotDropDownValueChanged, true);
            app.VoltrationPlotDropDown.Value = 'SD^2';
            app.PhiThresholdEditFieldLabel = uilabel(app.InstrumentsTab);
            app.PhiThresholdEditFieldLabel.Text = 'Phi Threshold (%)';
            app.PhiThresholdEditField = uieditfield(app.InstrumentsTab, 'numeric');
            app.PhiThresholdEditField.Limits = [0.8 1];
            app.PhiThresholdEditField.Value = 0.95;
            app.VoltrationTab = uitab(app.TabGroup);
            app.VoltrationTab.Title = 'Voltration';
            app.UITable2 = uitable(app.VoltrationTab);
            app.UITable2.ColumnName = {'Channel'; 'Min. Voltage'; 'ERF'; 'Channel No.'; 'Cali. Voltage'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnSortable = true;
            app.UITable2.ColumnEditable = [false false true true true];
            app.UpdateStatisticsButton = uibutton(app.VoltrationTab, 'push');
            app.UpdateStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateStatisticsButtonPushed, true);
            app.UpdateStatisticsButton.Text = 'Update Statistics';
            app.ParameterDropDown_2Label = uilabel(app.VoltrationTab);
            app.ParameterDropDown_2Label.Text = 'Parameter';
            app.ParameterDropDown_2 = uidropdown(app.VoltrationTab);
            app.ParameterDropDown_2.Items = {};
            app.ParameterDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDown_2ValueChanged, true);
            app.ParameterDropDown_2.Value = {};
            app.MaxVtSliderLabel = uilabel(app.VoltrationTab);
            app.MaxVtSliderLabel.Text = 'Max Vt';
            app.MaxVtSlider = uislider(app.VoltrationTab);
            app.MaxVtSlider.ValueChangedFcn = createCallbackFcn(app, @MaxVtSliderValueChanged, true);
            app.UIAxes2 = uiaxes(app.VoltrationTab);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Visible = 'off';
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.UITable3 = uitable(app.SummaryTab);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnSortable = true;
            app.InspectTab = uitab(app.TabGroup);
            app.InspectTab.Title = 'Inspect';
            app.LaserStatusDropDownLabel = uilabel(app.InspectTab);
            app.LaserStatusDropDownLabel.Text = 'Laser Status';
            app.LaserStatusDropDown = uidropdown(app.InspectTab);
            app.LaserStatusDropDown.Items = {};
            app.LaserStatusDropDown.ValueChangedFcn = createCallbackFcn(app, @LaserStatusDropDownValueChanged, true);
            app.LaserStatusDropDown.Value = {};
            app.TestConditionDropDownLabel = uilabel(app.InspectTab);
            app.TestConditionDropDownLabel.Text = 'Test Condition';
            app.TestConditionDropDown = uidropdown(app.InspectTab);
            app.TestConditionDropDown.Items = {};
            app.TestConditionDropDown.ValueChangedFcn = createCallbackFcn(app, @TestConditionDropDownValueChanged, true);
            app.TestConditionDropDown.Value = {};
            app.PulserIntensityDropDownLabel = uilabel(app.InspectTab);
            app.PulserIntensityDropDownLabel.Text = 'Pulser Intensity';
            app.PulserIntensityDropDown = uidropdown(app.InspectTab);
            app.PulserIntensityDropDown.Items = {};
            app.PulserIntensityDropDown.ValueChangedFcn = createCallbackFcn(app, @PulserIntensityDropDownValueChanged, true);
            app.PulserIntensityDropDown.Value = {};
            app.ParameterDropDownLabel = uilabel(app.InspectTab);
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.ParameterDropDown = uidropdown(app.InspectTab);
            app.ParameterDropDown.Items = {};
            app.ParameterDropDown.ValueChangedFcn = createCallbackFcn(app, @ParameterDropDownValueChanged, true);
            app.ParameterDropDown.Value = {};
            app.YAxisDropDownLabel = uilabel(app.InspectTab);
            app.YAxisDropDownLabel.Text = 'Y-Axis';
            app.YAxisDropDown = uidropdown(app.InspectTab);
            app.YAxisDropDown.Items = {'Intensity', 'SD^2', 'CV', 'Voltage'};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 'Intensity';
            app.XAxisDropDownLabel = uilabel(app.InspectTab);
            app.XAxisDropDownLabel.Text = 'X-Axis';
            app.XAxisDropDown = uidropdown(app.InspectTab);
            app.XAxisDropDown.Items = {'Intensity', 'SD^2', 'CV', 'Voltage'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 'Voltage';
            app.LogYAxisCheckBox = uicheckbox(app.InspectTab);
            app.LogYAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogYAxisCheckBoxValueChanged, true);
            app.LogYAxisCheckBox.Text = 'Log Y-Axis';
            app.LogXAxisCheckBox = uicheckbox(app.InspectTab);
            app.LogXAxisCheckBox.ValueChangedFcn = createCallbackFcn(app, @LogXAxisCheckBoxValueChanged, true);
            app.LogXAxisCheckBox.Text = 'Log X-Axis';
            app.UIAxes = uiaxes(app.InspectTab);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.ErrorsTab = uitab(app.TabGroup);
            app.ErrorsTab.Title = 'Errors';
            app.UITable = uitable(app.ErrorsTab);
            app.UITable.ColumnName = {'Parameter'; 'Stat 1'; 'Stat 2'; 'Stat 3'; 'Stat 4'};
            app.UITable.RowName = {};
            app.MinVoltageSliderLabel = uilabel(app.ErrorsTab);
            app.MinVoltageSliderLabel.Text = 'Min Voltage';
            app.MinVoltageSlider = uislider(app.ErrorsTab);
            app.MinVoltageSlider.ValueChangedFcn = createCallbackFcn(app, @MinVoltageSliderValueChanged, true);
            app.LogTab = uitab(app.TabGroup);
            app.LogTab.Title = 'Log';
            app.FilenamesListBox = uilistbox(app.LogTab);
            app.FilenamesListBox.Items = {};
            app.FilenamesListBox.Value = {};
            % Show the figure after all components are created
            app.Qb.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QbPASS_Master

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Qb)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Qb)
        end
    end
end